classdef USV_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        FilesTab                      matlab.ui.container.Tab
        testingLoadfilesButton        matlab.ui.control.Button
        testingLoadpathsButton        matlab.ui.control.Button
        PathEditField_Label           matlab.ui.control.EditField
        PathEditField_3Label          matlab.ui.control.Label
        PathEditField_PLabel          matlab.ui.control.EditField
        PathEditField_2Label          matlab.ui.control.Label
        PathEditField_USV             matlab.ui.control.EditField
        PathEditFieldLabel            matlab.ui.control.Label
        SelectLabelFiletxtButton      matlab.ui.control.Button
        SelectProvidedLabelFiletxtButton  matlab.ui.control.Button
        SelectUSVfilewavButton        matlab.ui.control.Button
        BlindDetectionTab             matlab.ui.container.Tab
        DetectionStatisticsTab        matlab.ui.container.Tab
        VisualisationTab              matlab.ui.container.Tab
        SegmentLengthsEditField       matlab.ui.control.NumericEditField
        SegmentLengthsEditFieldLabel  matlab.ui.control.Label
        SegmentStartsEditField        matlab.ui.control.NumericEditField
        SegmentStartsEditFieldLabel   matlab.ui.control.Label
        PlotButton                    matlab.ui.control.Button
        UIAxes_Spectrogram            matlab.ui.control.UIAxes
        UIAxes_USV                    matlab.ui.control.UIAxes
        InfoTab                       matlab.ui.container.Tab
        AntonnGazda20242025SupervisorLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        USV_Signal_filename              % recorded ultrasonic vocalisation
        USV_Signal_location % Description
        USV_Signal_path         % path where USV_Signal was loaded
      
        USV_ProvidedLabel_path % Description
        
        USV_Signal % Description
        fs

        SegmentStartIndex
        SegmentStopIndex
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectUSVfilewavButton
        function SelectUSVfilewavButtonPushed(app, event)
            

            [app.USV_Signal_filename, app.USV_Signal_location] = uigetfile('*.wav');
            app.USV_Signal_path = [app.USV_Signal_location,app.USV_Signal_filename];
            app.PathEditField_USV.Value = app.USV_Signal_path;

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)


            app.SegmentStartIndex = round(app.SegmentStartsEditField.Value * app.fs) + 1;
            app.SegmentStopIndex = round((app.SegmentStartsEditField.Value+app.SegmentLengthsEditField.Value) * app.fs);

        startIndex = app.SegmentStartIndex;
        stopIndex = app.SegmentStopIndex;

    % Extract the audio segment corresponding to the label
    audioSegment = app.USV_Signal(startIndex:stopIndex);

    % Create a time vector for the segment (in seconds)
    segmentTime = (startIndex:stopIndex) / app.fs;

    % Plot the waveform of the audio segment

    
    plot(app.UIAxes_USV, segmentTime, audioSegment);

    xlabel(app.UIAxes_USV,'Time (s)');
    ylabel(app.UIAxes_USV,'Amplitude');
    title(app.UIAxes_USV,'USV Vocalisation');
    axis(app.UIAxes_USV,'tight')
    grid(app.UIAxes_USV,"on")


    % 
    % % Compute the initial spectrogram (without denoising)
    % [s, f, t, p] = spectrogram(audioSegment, 256, [], [], app.fs, 'yaxis');
    % f_kHz = f / 1000; % Convert frequency to kHz for plotting
    % 
    % % Plot the plain spectrogram without any lines or processing
    % imagesc(app.UIAxes_Spectrogram,t, f_kHz, 10*log10(p));
    % axis(app.UIAxes_Spectrogram,"xy");
    % ylim(app.UIAxes_Spectrogram,[40 120]); % Set y-axis limits in kHz
    % title(app.UIAxes_Spectrogram,'Plain Spectrogram');
    % xlabel(app.UIAxes_Spectrogram,'Time (s)');
    % ylabel(app.UIAxes_Spectrogram,'Frequency (kHz)');
    % colormap(app.UIAxes_Spectrogram,"HSV");
    % colorbar(app.UIAxes_Spectrogram,"off");


        end

        % Button pushed function: testingLoadpathsButton
        function testingLoadpathsButtonPushed(app, event)
            app.USV_Signal_path = "/Users/gazda/Documents/CTU/Masters/Masters thesis/data/usv_audio/denoise/LPS-SI2homo-mH02-I04-USV_denoise.wav";
            app.USV_ProvidedLabel_path = "/Users/gazda/Documents/CTU/Masters/Masters thesis/data/labels/LPS-SI2homo-mH02-I04-USV-IVojt.txt";


            app.PathEditField_USV.Value = app.USV_Signal_path;
            app.PathEditField_PLabel.Value = app.USV_ProvidedLabel_path;
        end

        % Button pushed function: testingLoadfilesButton
        function testingLoadfilesButtonPushed(app, event)
            
        [app.USV_Signal, app.fs] = audioread(app.USV_Signal_path);
            
        end

        % Value changed function: SegmentStartsEditField
        function SegmentStartsEditFieldValueChanged(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FilesTab = uitab(app.TabGroup);
            app.FilesTab.Title = 'Files';
            app.SelectUSVfilewavButton = uibutton(app.FilesTab, 'push');
            app.SelectUSVfilewavButton.ButtonPushedFcn = createCallbackFcn(app, @SelectUSVfilewavButtonPushed, true);
            app.SelectUSVfilewavButton.Text = 'Select USV file (.wav)';
            app.SelectProvidedLabelFiletxtButton = uibutton(app.FilesTab, 'push');
            app.SelectProvidedLabelFiletxtButton.Text = 'Select Provided Label File (.txt)';
            app.SelectLabelFiletxtButton = uibutton(app.FilesTab, 'push');
            app.SelectLabelFiletxtButton.Text = 'Select Label File (.txt)';
            app.PathEditFieldLabel = uilabel(app.FilesTab);
            app.PathEditFieldLabel.Text = 'Path';
            app.PathEditField_USV = uieditfield(app.FilesTab, 'text');
            app.PathEditField_2Label = uilabel(app.FilesTab);
            app.PathEditField_2Label.Text = 'Path';
            app.PathEditField_PLabel = uieditfield(app.FilesTab, 'text');
            app.PathEditField_3Label = uilabel(app.FilesTab);
            app.PathEditField_3Label.Text = 'Path';
            app.PathEditField_Label = uieditfield(app.FilesTab, 'text');
            app.testingLoadpathsButton = uibutton(app.FilesTab, 'push');
            app.testingLoadpathsButton.ButtonPushedFcn = createCallbackFcn(app, @testingLoadpathsButtonPushed, true);
            app.testingLoadpathsButton.Text = '(testing) Load paths';
            app.testingLoadfilesButton = uibutton(app.FilesTab, 'push');
            app.testingLoadfilesButton.ButtonPushedFcn = createCallbackFcn(app, @testingLoadfilesButtonPushed, true);
            app.testingLoadfilesButton.Text = '(testing) Load files';
            app.BlindDetectionTab = uitab(app.TabGroup);
            app.BlindDetectionTab.Title = 'Blind Detection';
            app.DetectionStatisticsTab = uitab(app.TabGroup);
            app.DetectionStatisticsTab.Title = 'Detection Statistics';
            app.VisualisationTab = uitab(app.TabGroup);
            app.VisualisationTab.Title = 'Visualisation';
            app.UIAxes_USV = uiaxes(app.VisualisationTab);
            title(app.UIAxes_USV, 'USV SIgnal')
            xlabel(app.UIAxes_USV, 'X')
            ylabel(app.UIAxes_USV, 'Y')
            zlabel(app.UIAxes_USV, 'Z')
            app.UIAxes_Spectrogram = uiaxes(app.VisualisationTab);
            title(app.UIAxes_Spectrogram, 'Title')
            xlabel(app.UIAxes_Spectrogram, 'X')
            ylabel(app.UIAxes_Spectrogram, 'Y')
            zlabel(app.UIAxes_Spectrogram, 'Z')
            app.PlotButton = uibutton(app.VisualisationTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SegmentStartsEditFieldLabel = uilabel(app.VisualisationTab);
            app.SegmentStartsEditFieldLabel.Text = 'Segment Start (s)';
            app.SegmentStartsEditField = uieditfield(app.VisualisationTab, 'numeric');
            app.SegmentStartsEditField.ValueChangedFcn = createCallbackFcn(app, @SegmentStartsEditFieldValueChanged, true);
            app.SegmentStartsEditField.Value = 60;
            app.SegmentLengthsEditFieldLabel = uilabel(app.VisualisationTab);
            app.SegmentLengthsEditFieldLabel.Text = 'Segment Length (s)';
            app.SegmentLengthsEditField = uieditfield(app.VisualisationTab, 'numeric');
            app.SegmentLengthsEditField.Value = 10;
            app.InfoTab = uitab(app.TabGroup);
            app.InfoTab.Title = 'Info';
            app.AntonnGazda20242025SupervisorLabel = uilabel(app.InfoTab);
            app.AntonnGazda20242025SupervisorLabel.Text = {'Master Thesis: Antonín Gazda'; ''; '2024/25'; ''; 'Supervisor: prof. Ing. Roman Čmejla, CSc.'; ''; 'CTU FEE'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = USV_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end